classdef bwgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PreviewVideoButton          matlab.ui.control.Button
        GeneratebwButton            matlab.ui.control.Button
        GenerateParaButton          matlab.ui.control.Button
        AreaopensizeSpinner         matlab.ui.control.Spinner
        AreaopensizeSpinnerLabel    matlab.ui.control.Label
        FrameLabel                  matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        FrameSpinner                matlab.ui.control.Spinner
        GuassianfilterSpinner       matlab.ui.control.Spinner
        GuassianfilterSpinnerLabel  matlab.ui.control.Label
        BandpassmaxSpinner          matlab.ui.control.Spinner
        BandpassmaxSpinnerLabel     matlab.ui.control.Label
        BandpassminSpinner          matlab.ui.control.Spinner
        PreviewButton               matlab.ui.control.Button
        BandpassminSpinnerLabel     matlab.ui.control.Label
        ImageAxes2                  matlab.ui.control.UIAxes
        ImageAxes1                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        imgStack % Raw image
        imgOriginal
        frame
        bwPara
    end

    methods (Access = private)

        function bw = generatebw(app)
            img = app.imgOriginal;
            [bpimg, ~] = bandpassfilt(img, app.bwPara.bpmin, app.bwPara.bpmax);
            bw = imbinarize(imgaussfilt(bpimg, app.bwPara.gaussSigma), 'adaptive');
            bw = bwareaopen(bw, app.bwPara.areaopenSize);
        end

        function bw = generatebwstack(app, nImg)
            f = waitbar(0,'0.0%','Name','Binarzing images...',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            setappdata(f,'canceling',0);

            bw = false([size(app.imgStack, [1 2]), nImg]);

            for iImg = 1:nImg
                % Check for clicked Cancel button
                if getappdata(f,'canceling')
                    break
                end
                bw(:,:,iImg) = getbw(app.imgStack(:,:,iImg), app.bwPara);
                waitbar(iImg/nImg,f,sprintf('%4.1f%%',iImg/nImg*100));
            end
            delete(f);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img)
            app.FrameSlider.Value = 1;
            app.imgStack = img;
            app.imgOriginal = app.imgStack(:,:,app.FrameSlider.Value);
            %  default value
            app.bwPara.bpmax = 6;
            app.bwPara.bpmin = 1;
            app.bwPara.gaussSigma = 1;
            app.bwPara.areaopenSize = 8;
            if  size(img,3) == 1
                app.FrameSlider.Visible = "off";
            else

                app.FrameSlider.Limits = [1, size(img,3)];
            end
            app.FrameSlider.Value = 1;
            app.FrameSpinner.Value = 1;

            axis(app.ImageAxes1, 'image');
            axis(app.ImageAxes2, 'image');
            disableDefaultInteractivity(app.ImageAxes1);  % Fix bug of fleur pointer outside of roi. Fix at R2022b according to Mathwork.
            disableDefaultInteractivity(app.ImageAxes2);

            imshow(mat2gray(app.imgOriginal), 'Parent', app.ImageAxes1);
        end

        % Value changed function: BandpassmaxSpinner
        function BandpassmaxSpinnerValueChanged(app, event)
            app.bwPara.bpmax = app.BandpassmaxSpinner.Value;
        end

        % Value changed function: BandpassminSpinner
        function BandpassminSpinnerValueChanged(app, event)
            app.bwPara.bpmin = app.BandpassminSpinner.Value;
        end

        % Value changed function: GuassianfilterSpinner
        function GuassianfilterSpinnerValueChanged(app, event)
            app.bwPara.gaussSigma = app.GuassianfilterSpinner.Value;
        end

        % Value changed function: AreaopensizeSpinner
        function AreaopensizeSpinnerValueChanged(app, event)
            app.bwPara.areaopenSize = app.AreaopensizeSpinner.Value;
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            f = waitbar(0,'Please wait...');
            bw1 = generatebw(app);
            imshow(mat2gray(app.imgOriginal), 'Parent', app.ImageAxes1);
            imshow(bw1, 'Parent', app.ImageAxes2);
            waitbar(1,f,'Finishing');
            close(f);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.FrameSlider.Value = round(app.FrameSlider.Value);
            app.FrameSpinner.Value = app.FrameSlider.Value;
            app.imgOriginal = app.imgStack(:,:,app.FrameSpinner.Value);
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            app.FrameSlider.Value = app.FrameSpinner.Value;
            app.imgOriginal = app.imgStack(:,:,app.FrameSpinner.Value);
        end

        % Button pushed function: GenerateParaButton
        function GenerateParaButtonPushed(app, event)
            %% Save [bwPara] in the unrepeatable varname
            rawname = 'bwPara';
            iName = 0;
            savename = rawname;
            varname = evalin('base','whos'); %or 'base'
            varlist = [varname(:).name];
            while ismember(savename, varlist)
                iName = iName + 1;
                num = sprintf('%02d', iName);
                savename = [rawname num];
            end
            assignin("base", savename, app.bwPara);
            disp(['-------Binarized parameters saved in-------' savename]);
            %% Save [bwPara] in [bwPara]
            %             assignin("base", "bwPara", app.bwPara);
        end

        % Button pushed function: GeneratebwButton
        function GeneratebwButtonPushed(app, event)
            nImg = size(app.imgStack, 3);
            bw = generatebwstack(app, nImg);

            %% Save [bw] in the unrepeatable varname
            rawname = 'bw';
            iName = 0;
            savename = rawname;
            varname = evalin('base','whos'); %or 'base'
            varlist = [varname(:).name];
            while ismember(savename, varlist)
                iName = iName + 1;
                num = sprintf('%02d', iName);
                savename = [rawname num];
            end
            assignin("base", savename, bw);

            disp(['-------Binarized images saved in-------' savename]);
            %%  Save [bw] in [bwFromGUI]
            %             assignin("base", "bwFromGUI", bw);
            %             delete(f);
            %             disp('-------Binarized images saved in------- bwFromGUI');
        end

        % Button pushed function: PreviewVideoButton
        function PreviewVideoButtonPushed(app, event)
            bw = generatebwstack(app, 10);
            implay(bw);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes1 = uiaxes(app.UIFigure);
            app.ImageAxes1.XTick = [];
            app.ImageAxes1.XTickLabel = {'[ ]'};
            app.ImageAxes1.YTick = [];
            app.ImageAxes2 = uiaxes(app.UIFigure);
            app.ImageAxes2.XTick = [];
            app.ImageAxes2.XTickLabel = {'[ ]'};
            app.ImageAxes2.YTick = [];
            app.BandpassminSpinnerLabel = uilabel(app.UIFigure);
            app.BandpassminSpinnerLabel.Text = 'Bandpass min';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.BandpassminSpinner = uispinner(app.UIFigure);
            app.BandpassminSpinner.ValueChangedFcn = createCallbackFcn(app, @BandpassminSpinnerValueChanged, true);
            app.BandpassminSpinner.Value = 1;
            app.BandpassmaxSpinnerLabel = uilabel(app.UIFigure);
            app.BandpassmaxSpinnerLabel.Text = 'Bandpass max';
            app.BandpassmaxSpinner = uispinner(app.UIFigure);
            app.BandpassmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @BandpassmaxSpinnerValueChanged, true);
            app.BandpassmaxSpinner.Value = 6;
            app.GuassianfilterSpinnerLabel = uilabel(app.UIFigure);
            app.GuassianfilterSpinnerLabel.Text = 'Guassian filter';
            app.GuassianfilterSpinner = uispinner(app.UIFigure);
            app.GuassianfilterSpinner.ValueChangedFcn = createCallbackFcn(app, @GuassianfilterSpinnerValueChanged, true);
            app.GuassianfilterSpinner.Value = 1;
            app.FrameSpinner = uispinner(app.UIFigure);
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Value = 1;
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
            app.AreaopensizeSpinnerLabel = uilabel(app.UIFigure);
            app.AreaopensizeSpinnerLabel.Text = 'Areaopen size';
            app.AreaopensizeSpinner = uispinner(app.UIFigure);
            app.AreaopensizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AreaopensizeSpinnerValueChanged, true);
            app.AreaopensizeSpinner.Value = 8;
            app.GenerateParaButton = uibutton(app.UIFigure, 'push');
            app.GenerateParaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateParaButtonPushed, true);
            app.GenerateParaButton.Text = 'Generate Para';
            app.GeneratebwButton = uibutton(app.UIFigure, 'push');
            app.GeneratebwButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratebwButtonPushed, true);
            app.GeneratebwButton.Text = 'Generate bw';
            app.PreviewVideoButton = uibutton(app.UIFigure, 'push');
            app.PreviewVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewVideoButtonPushed, true);
            app.PreviewVideoButton.Text = 'Preview Video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bwgui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end